classdef Miadas_touch_FV2_front < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        AddnodeButton                  matlab.ui.control.Button
        NodeNameEditFieldLabel         matlab.ui.control.Label
        NodeNameEditField              matlab.ui.control.EditField
        AddlinkButton                  matlab.ui.control.Button
        CapOutEditFieldLabel           matlab.ui.control.Label
        CapOutEditField                matlab.ui.control.NumericEditField
        CapNodeEditFieldLabel          matlab.ui.control.Label
        CapNodeEditField               matlab.ui.control.NumericEditField
        CapInEditFieldLabel            matlab.ui.control.Label
        CapInEditField                 matlab.ui.control.NumericEditField
        FromEditFieldLabel             matlab.ui.control.Label
        FromEditField                  matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        ToEditField                    matlab.ui.control.NumericEditField
        CapLinkEditFieldLabel          matlab.ui.control.Label
        CapLinkEditField               matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        UnitStepLabel                  matlab.ui.control.Label
        UnitsizeEditField              matlab.ui.control.NumericEditField
        PlotthenetworkButton           matlab.ui.control.Button
        StartnewsystemButton           matlab.ui.control.Button
        PlotnodeButton                 matlab.ui.control.Button
        plotallnodesButton             matlab.ui.control.Button
        NodenumberEditFieldLabel       matlab.ui.control.Label
        NodenumberEditField            matlab.ui.control.NumericEditField
        HoldCheckBox                   matlab.ui.control.CheckBox
        PlotalllinksButton             matlab.ui.control.Button
        HoldCheckBox_2                 matlab.ui.control.CheckBox
        PlotlinkButton                 matlab.ui.control.Button
        ClearButton_2                  matlab.ui.control.Button
        ClearButton_3                  matlab.ui.control.Button
        NodeparameterListBoxLabel      matlab.ui.control.Label
        NodeparameterListBox           matlab.ui.control.ListBox
        LinkparameterListBoxLabel      matlab.ui.control.Label
        LinkparameterListBox           matlab.ui.control.ListBox
        SimulateButton                 matlab.ui.control.Button
        DemandfunctionListBoxLabel     matlab.ui.control.Label
        DemandfunctionListBox          matlab.ui.control.ListBox
        SupplyfunctionListBoxLabel     matlab.ui.control.Label
        SupplyfunctionListBox          matlab.ui.control.ListBox
        TypeofnodeListBoxLabel         matlab.ui.control.Label
        TypeofnodeListBox              matlab.ui.control.ListBox
        InInvEditFieldLabel            matlab.ui.control.Label
        InInvEditField                 matlab.ui.control.NumericEditField
        FinancialofthesystemTextAreaLabel  matlab.ui.control.Label
        FinancialofthesystemTextArea   matlab.ui.control.TextArea
        VarcostEditFieldLabel          matlab.ui.control.Label
        VarcostEditField               matlab.ui.control.NumericEditField
        FixedcostEditField_2Label      matlab.ui.control.Label
        FixedcostEditField_2           matlab.ui.control.NumericEditField
        ProcostEditFieldLabel          matlab.ui.control.Label
        ProcostEditField               matlab.ui.control.NumericEditField
        RevEditFieldLabel              matlab.ui.control.Label
        RevEditField                   matlab.ui.control.NumericEditField
        VeractionEditFieldLabel        matlab.ui.control.Label
        VeractionEditField             matlab.ui.control.NumericEditField
        TimebackEditFieldLabel         matlab.ui.control.Label
        TimebackEditField              matlab.ui.control.NumericEditField
        TimedivEditFieldLabel          matlab.ui.control.Label
        TimedivEditField               matlab.ui.control.NumericEditField
        UnitcostEditFieldLabel         matlab.ui.control.Label
        UnitcostEditField              matlab.ui.control.NumericEditField
        VereactionfixedEditFieldLabel  matlab.ui.control.Label
        VereactionfixedEditField       matlab.ui.control.NumericEditField
        VereactionvarEditFieldLabel    matlab.ui.control.Label
        VereactionvarEditField         matlab.ui.control.NumericEditField
        errorEditFieldLabel            matlab.ui.control.Label
        errorEditField                 matlab.ui.control.NumericEditField
        TimeperiodtosimulateEditFieldLabel  matlab.ui.control.Label
        TimeperiodtosimulateEditField  matlab.ui.control.NumericEditField
        DCoeffEditFieldLabel           matlab.ui.control.Label
        DCoeffEditField                matlab.ui.control.NumericEditField
        LinknumberEditFieldLabel       matlab.ui.control.Label
        LinknumberEditField            matlab.ui.control.NumericEditField
        SCoeffEditFieldLabel           matlab.ui.control.Label
        SCoeffEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
    
        %I/P to access in buttons
        %node
        
        NodeName;
        CapIn;
        CapOut;
        CapNode;
        InInv;
        FixedCost;
        VarCost;
        ProCost;
        Rev;
        NegReact;
        SupplyFunV;
        demandcoff;
        DemandFun;
        supplycoff;
        TypeV;

        %link
        From;
        To;
        CapLink;
        Time;
        UnitStep;
        TimrBack;
        TimeDev;
        UnitCost;
        NegReactFixed;
        NegReactVar;
        Error;

        %I/P database
        NodeNameV; % node names
        CapInV; 
        CapOutV;
        CapNodeV;
        DemandFunV;
        
        InInvV;
        FixedCostV;
        VarCostV;
        ProCostV;
        RevV;
        NegReactV;
        %links
        FromV;
        ToV;
        CapLinkV;
        TimeV;
        UnitStepV;
        
        TimrBackV;
        TimeDevV;
        UnitCostV;
        NegReactFixedV;
        NegReactVarV;
        ErrorV;
        
        %time period to simulate
        Period

        %Plot
        NodeNumberPlot;
        LinkNumberPlot;

        %nodes counter
        CountN;
        %links counter
        CountL;

        %data from python
        data;

        outputdis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            app.Time = app.TimeEditField.Value;
        end

        % Value changed function: CapLinkEditField
        function CapLinkEditFieldValueChanged(app, event)
            app.CapLink = app.CapLinkEditField.Value;
        end

        % Value changed function: ToEditField
        function ToEditFieldValueChanged(app, event)
            app.To = app.ToEditField.Value;          
        end

        % Value changed function: FromEditField
        function FromEditFieldValueChanged(app, event)
            app.From = app.FromEditField.Value;           
        end

        % Value changed function: CapInEditField
        function CapInEditFieldValueChanged(app, event)
            app.CapIn = app.CapInEditField.Value;          
        end

        % Value changed function: CapNodeEditField
        function CapNodeEditFieldValueChanged(app, event)
            app.CapNode = app.CapNodeEditField.Value;          
        end

        % Value changed function: CapOutEditField
        function CapOutEditFieldValueChanged(app, event)
            app.CapOut = app.CapOutEditField.Value;          
        end

        % Button pushed function: AddlinkButton
        function AddlinkButtonPushed(app, event)

        if (app.CountL == 0)
            app.FromV = [];
            app.ToV = [];
            app.CapLinkV = [];
            app.TimeV = [];
            app.UnitStepV = [];
            app.TimrBackV = [];
            app.TimeDevV = [];
            app.UnitCostV = [];
            app.NegReactFixedV = {};
            app.NegReactVarV = {};
            app.ErrorV = {};

            app.FromV(end+1) = app.From;
            app.ToV(end+1) = app.To;
            app.CapLinkV(end+1) = app.CapLink;
            app.TimeV(end+1) = app.Time;
            app.UnitStepV(end+1) = app.UnitStep;
            app.TimrBackV(end+1) = app.TimrBack;
            app.TimeDevV(end+1) = app.TimeDev;
            app.UnitCostV(end+1) = app.UnitCost;
            app.NegReactFixedV{end+1} = app.NegReactFixed;
            app.NegReactVarV{end+1} = app.NegReactVar;
            app.ErrorV{end+1} = app.Error;
            app.CountL = app.CountL+1;
        else
            app.FromV(end+1) = app.From;
            app.ToV(end+1) = app.To;
            app.CapLinkV(end+1) = app.CapLink;
            app.TimeV(end+1) = app.Time;
            app.UnitStepV(end+1) = app.UnitStep;
            app.TimrBackV(end+1) = app.TimrBack;
            app.TimeDevV(end+1) = app.TimeDev;
            app.UnitCostV(end+1) = app.UnitCost;
            app.NegReactFixedV{end+1} = app.NegReactFixed;
            app.NegReactVarV{end+1} = app.NegReactVar;
            app.ErrorV{end+1} = app.Error;
            app.CountL = app.CountL+1;
        end
        end

        % Value changed function: NodeNameEditField
        function NodeNameEditFieldValueChanged(app, event)
        app.NodeName = app.NodeNameEditField.Value;
        end

        % Button pushed function: AddnodeButton
        function AddnodeButtonPushed(app, event)
selsupply = app.SupplyfunctionListBox.Value;
seldemand = app.DemandfunctionListBox.Value;
seltype = app.TypeofnodeListBox.Value;
        if (app.CountN == 0) 
            app.NodeNameV = {};
            app.CapInV = [];
            app.CapOutV = [];
            app.CapNodeV = [];
            app.InInvV = {};
            app.FixedCostV = [];
            app.VarCostV = [];
            app.ProCostV = [];
            app.RevV = [];
            app.NegReactV = [];
            app.SupplyFunV = [];
            app.DemandFunV=[];
            app.TypeV = {};
            
            app.NodeNameV{end+1} = app.NodeName;
            app.CapInV(end+1) = app.CapIn;
            app.CapOutV(end+1) = app.CapOut;
            app.CapNodeV(end+1) = app.CapNode;
            app.InInvV{end+1} = app.InInv;
            app.FixedCostV(end+1) = app.FixedCost;
            app.VarCostV(end+1) = app.VarCost;
            app.ProCostV(end+1) = app.ProCost;
            app.RevV(end+1) = app.Rev;
            app.NegReactV(end+1) = app.NegReact;
             app.Period = app.TimeperiodtosimulateEditField.Value;
            
             switch selsupply

                case 'triangle'
                    app.SupplyFunV(app.CountN+1,:) = ones(1,10*app.Period);
                    for i = 1:(10*app.Period/2)
                        app.SupplyFunV(app.CountN+1,i) = app.supplycoff*i;
                        app.SupplyFunV(app.CountN+1,10*app.Period - i - 1) = app.supplycoff*i;
                    end

                case 'unitstep'
                    app.SupplyFunV(app.CountN+1,:) = ones(1,10*app.Period)*app.supplycoff;
                case 'none'
                    app.SupplyFunV(app.CountN+1,:) = zeros(1, 10*app.Period);
             end

            switch seldemand
                case 'triangle'
                    app.DemandFunV(app.CountN+1,:) = ones(1,10*app.Period);
                    for i = 1:(10*app.Period/2)
                        app.DemandFunV(app.CountN+1,i) = app.demandcoff*i;
                        app.DemandFunV(app.CountN+1,10*app.Period - i - 1) = app.demandcoff*i;
                    end
                case 'unitstep'
                    app.DemandFunV(app.CountN+1,:) = ones(1,10*app.Period)*app.demandcoff;
                    
                case 'none'
                    app.DemandFunV(app.CountN+1,:) = zeros(1, 10*app.Period);
            end

            switch seltype
                case 'Source'
                    app.TypeV{end+1} = 'source';
                case 'Drain'
                    app.TypeV{end+1} = 'drain';
                case 'Both'
                    app.TypeV{end+1} = 'both';
                case 'Intermadiate'
                    app.TypeV{end+1} = 'intermadiate';
            end
        else
            app.NodeNameV{end+1} = app.NodeName;
            app.CapInV(end+1) = app.CapIn;
            app.CapOutV(end+1) = app.CapOut;
            app.CapNodeV(end+1) = app.CapNode;
            app.InInvV{end+1} = app.InInv;
            app.FixedCostV(end+1) = app.FixedCost;
            app.VarCostV(end+1) = app.VarCost;
            app.ProCostV(end+1) = app.ProCost;
            app.RevV(end+1) = app.Rev;
            app.NegReactV(end+1) = app.NegReact;
            
            switch selsupply
                
                case 'triangle'
                 %   half_time= 10* app.period /2 ;
                    app.SupplyFunV(app.CountN+1,:) = ones(1,10*app.Period);
                    for i = 1:(10*app.Period/2)
                    app.SupplyFunV(app.CountN+1,i) = app.supplycoff*i;
                    app.SupplyFunV(app.CountN+1,10*app.Period - i - 1) = app.supplycoff*i;
                    end
                
                case 'unitstep'
                    app.SupplyFunV(app.CountN+1,:) = ones(1,10*app.Period);
                
                case 'none'
                    app.SupplyFunV(app.CountN+1,:) = zeros(1, 10*app.Period);
            end

            switch seldemand
                case 'triangle'
                    app.DemandFunV(app.CountN+1,:) = ones(1,10*app.Period);
                    for i = 1:(10*app.Period/2)
                    app.DemandFunV(app.CountN+1,i) = app.demandcoff*i;
                    app.DemandFunV(app.CountN+1,10*app.Period - i - 1) = app.demandcoff*i;
                    end
                
                case 'unitstep'
                    app.DemandFunV(app.CountN+1,:) = ones(1,10*app.Period);
                
                case 'none'
                    app.DemandFunV(app.CountN+1,:) = zeros(1, 10*app.Period);
            end

            switch seltype
                case 'Source'
                    app.TypeV{end+1} = 'Source';
                case 'Drain'
                    app.TypeV{end+1} = 'Drain';
                case 'Both'
                    app.TypeV{end+1} = 'Both';
                case 'Intermadiate'
                    app.TypeV{end+1} = 'Intermadiate';
            end
        end

        app.CountN = app.CountN+1;
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: UnitsizeEditField
        function UnitsizeEditFieldValueChanged(app, event)
        app.UnitStep = app.UnitsizeEditField.Value;
        end

        % Button pushed function: StartnewsystemButton
        function StartnewsystemButtonPushed(app, event)
        app.CountL = 0;
        app.CountN = 0;
        end

        % Button pushed function: PlotthenetworkButton
        function PlotthenetworkButtonPushed(app, event)
%adjmatrix
A = zeros(app.CountN,app.CountN);
for i = 1:app.CountL
A(app.FromV(1,i) , app.ToV(1,i)) = 1;
end

G = digraph(A);
plot(app.UIAxes,G ...
    ,"NodeLabel", app.NodeNameV ...
    ,"NodeColor", 'red' ... 
    ,'EdgeColor', 'k' ... 
    ,'LineWidth', 2 ...
    ,'EdgeLabel', app.CapLinkV ...
    ,'ArrowSize', 12 ...
    ,'Layout', 'circle' ...
    ,'NodeFontSize', 10);
        end

        % Callback function
        function testButtonPushed(app, event)
            
        end

        % Button pushed function: PlotnodeButton
        function PlotnodeButtonPushed(app, event)
 node_number = app.NodenumberEditField.Value;
    sellected = app.NodeparameterListBox.Value;
    x = [];
    y = [];
    data = app.data;
    data{'nodes'}
%x = zeros(size(t)); % Initialize x with zeros

 for i=1:app.CountN
    if (node_number==i)
        switch  sellected
      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%          
            case 'Supply'
                for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                end 
            plot(app.UIAxes2,x,app.SupplyFunV(i,:), 'DisplayName', sprintf('supply fun (Node %d)', node_number)); 
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            case 'Demand'
                 for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                end 
            plot(app.UIAxes2,x,app.DemandFunV(i,:), 'DisplayName', sprintf('demand fun (Node %d)', node_number)); 
                
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            case 'Prorate'
                  for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                    y(k) = double(data{'nodes'}{app.NodeNameV{node_number}}{'processing_rate'}{k});
                end
                
                plot(app.UIAxes2,x,y, 'DisplayName', sprintf('processing rate (Node %d)', node_number)); 
 
              
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            case 'Invin'
                for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                    y(k) = double(data{'nodes'}{app.NodeNameV{node_number}}{'invIn'}{k});
                end
                
                 plot(app.UIAxes2,x,y, 'DisplayName', sprintf('invin (Node %d)', node_number)); 
             
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%              
            case 'Inout'
                    for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                    y(k) = double(data{'nodes'}{app.NodeNameV{node_number}}{'invOut'}{k});
                end
                
                 plot(app.UIAxes2,x,y, 'DisplayName', sprintf('invout (Node %d)', node_number)); 
          
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            case 'Cost'    
                  for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                    y(k) = double(app.data{'nodes'}{app.NodeNameV{node_number}}{'costs'}{k});
                end
                
                plot(app.UIAxes2,x,y, 'DisplayName',sprintf('cost (Node %d)', node_number));
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             
            case 'Rev'    
                  for k = 1:length(data{'time'})
                    x(k) = double(data{'time'}{k});
                    y(k) = double(app.data{'nodes'}{app.NodeNameV{node_number}}{'revenue'}{k});
                end
                
                plot(app.UIAxes2,x,y, 'DisplayName', sprintf('revenue (Node %d)', node_number));              
               
        end
    end
     
 end
 legend(app.UIAxes2, 'Location', 'best'); 
        end

        % Callback function
        function parameterListBox_2ValueChanged(app, event)
    
        end

        % Callback function
        function PlotButtonPushed(app, event)
   
        end

        % Value changed function: NodenumberEditField
        function NodenumberEditFieldValueChanged(app, event)
           app.NodeNumberPlot = app.NodenumberEditField.Value;
        end

        % Callback function
        function LinknumberEditFieldValueChanged(app, event)
            Link_number = app.LinknumberEditField.Value;      
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            value = app.HoldCheckBox.Value;
            if(value==1)
                  hold(app.UIAxes2, 'on');
            else
                  hold(app.UIAxes2, 'off');     
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
cla(app.UIAxes2);
        end

        % Value changed function: NodeparameterListBox
        function NodeparameterListBoxValueChanged(app, event)
           sellected = app.NodeparameterListBox.Value;
        end

        % Callback function
        function FinancialParameterListBoxValueChanged(app, event)
            value = app.FinancialParameterListBox.Value;
        end

        % Value changed function: HoldCheckBox_2
        function HoldCheckBox_2ValueChanged(app, event)
            value = app.HoldCheckBox_2.Value;
            if(value==1)
                  hold(app.UIAxes3, 'on');
            else
                  hold(app.UIAxes3, 'off');     
            end
        end

        % Callback function
        function HoldCheckBox_3ValueChanged(app, event)
            value = app.HoldCheckBox_3.Value;
             if(value==1)
                  hold(app.UIAxes2_2, 'on');
            else
                  hold(app.UIAxes2_2, 'off');     
            end
        end

        % Callback function
        function ClearButtonPushed(app, event)
              cla(app.UIAxes2_2);
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
              cla(app.UIAxes3);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

insert(py.sys.path, int32(0), '"F:\dodys\math_project\supply_chain_constructor.py"');
test_module = py.importlib.import_module('matlab_interface');

number_of_nodes = int32(app.CountN);
number_of_links = int32(app.CountL);


node_parameter = cell(1,number_of_nodes );
link_parameter = cell(1, number_of_links );
simTime = int32(app.Period*10);
for i = 1:app.CountN
%    app.NodeNameV = {};
%             app.CapInV = [];
%             app.CapOutV = [];
%             app.CapNodeV = [];
%             app.InInvV = [];
%             app.FixedCostV = [];
%             app.VarCostV = [];
%             app.ProCostV = [];
%             app.RevV = [];
%             app.NegReactV = [];
%             app.SupplyFunV = [];
%             app.TypeV = {};
%        name, type, inv, capacity , capin,capout , fixed cost , var cost , process cost , supply func , demand func , neg.
%        reaction,rev
                 disp(app.SupplyFunV(i))

node_parameter{1,i} ={app.NodeNameV{i} , app.TypeV{i}, app.InInvV{i},  app.CapNodeV(i), app.CapInV(i), app.CapOutV(i) ,app.FixedCostV(i) , app.VarCostV(i) , app.ProCostV(i) , app.SupplyFunV(i,:), app.DemandFunV(i,:), app.NegReactV(i) , app.RevV(i)};  
end

for j=1:app.CountL
  
    %start , end , capacity , time , unit Size , timeDev (percentage) , timeBack , unit cost , negative Reaction (percentage), negative Reaction variable (percentage), reactionDev (percentage)

    link_parameter{1,j} = {app.NodeNameV{app.FromV(j)}, app.NodeNameV{app.ToV(j)},app.CapLinkV(j),app.TimeV(j), app.UnitStepV(j) , app.TimeDevV(j) , app.TimrBackV(j) , app.UnitCostV(j) ,app.NegReactFixedV{j} ,app.NegReactVarV{j} , app.ErrorV{j}};

   
end

disp(link_parameter{1}{9})
app.data = test_module.construct_and_simulate(simTime,number_of_nodes,node_parameter,number_of_links,link_parameter);
        
app.outputdis = [fprintf('The total cost = %d\n', app.data{'system'}{'cost'}) , fprintf('The total revenue = %d\n', app.data{'system'}{'revenue'}) ];

        end

        % Button pushed function: PlotlinkButton
        function PlotlinkButtonPushed(app, event)
sellected = app.LinkparameterListBox.Value;
 app.LinkNumberPlot = app.LinknumberEditField.Value;
 
     app.data{'links'}
for i=1:app.CountL
    if (app.LinkNumberPlot==i)
       
switch  sellected
            case 'UnitNow'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{app.LinkNumberPlot}{'unitsNow'}{i});  
                end
                plot(app.UIAxes3, x, y, 'DisplayName', sprintf('unitsnow (Link %d)', items));
            case 'UnitBack'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{app.LinkNumberPlot}{'unitsBack'}{i});   
                end
                plot(app.UIAxes3, x, y, 'DisplayName', sprintf('unitsBack (Link %d)', i));
          
    case 'Cost'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{app.LinkNumberPlot}{'cost'}{i});
                 end
                plot(app.UIAxes3, x, y, 'DisplayName', sprintf('cost (Link %d)', i));
end
end
        end
   legend(app.UIAxes3, 'Location', 'best'); 
   
        end

        % Value changed function: LinkparameterListBox
        function LinkparameterListBoxValueChanged(app, event)
            value = app.LinkparameterListBox.Value;
            
        end

        % Callback function
        function PlotButtonPushed2(app, event)
 
        end

        % Value changed function: InInvEditField
        function InInvEditFieldValueChanged(app, event)
            app.InInv = app.InInvEditField.Value;
        end

        % Value changed function: FixedcostEditField_2
        function FixedcostEditField_2ValueChanged(app, event)
            app.FixedCost = app.FixedcostEditField_2.Value;
        end

        % Value changed function: VarcostEditField
        function VarcostEditFieldValueChanged(app, event)
            app.VarCost = app.VarcostEditField.Value;
            
        end

        % Value changed function: ProcostEditField
        function ProcostEditFieldValueChanged(app, event)
            app.ProCost = app.ProcostEditField.Value;
        end

        % Value changed function: RevEditField
        function RevEditFieldValueChanged(app, event)
            app.Rev = app.RevEditField.Value;
        end

        % Value changed function: VeractionEditField
        function VeractionEditFieldValueChanged(app, event)
            app.NegReact = app.VeractionEditField.Value;
        end

        % Value changed function: TimebackEditField
        function TimebackEditFieldValueChanged(app, event)
            app.TimrBack = app.TimebackEditField.Value;
        end

        % Value changed function: TimedivEditField
        function TimedivEditFieldValueChanged(app, event)
            app.TimeDev = app.TimedivEditField.Value;
        end

        % Value changed function: UnitcostEditField
        function UnitcostEditFieldValueChanged(app, event)
            app.UnitCost = app.UnitcostEditField.Value;
        end

        % Value changed function: VereactionfixedEditField
        function VereactionfixedEditFieldValueChanged(app, event)
            app.NegReactFixed = app.VereactionfixedEditField.Value;
        end

        % Value changed function: VereactionvarEditField
        function VereactionvarEditFieldValueChanged(app, event)
            app.NegReactVar = app.VereactionvarEditField.Value;
        end

        % Value changed function: errorEditField
        function errorEditFieldValueChanged(app, event)
            app.Error = app.errorEditField.Value;
        end

        % Value changed function: TimeperiodtosimulateEditField
        function TimeperiodtosimulateEditFieldValueChanged(app, event)
            app.Period = app.TimeperiodtosimulateEditField.Value;
        end

        % Callback function
        function FromEditField_2ValueChanged(app, event)
            app.FromPlot = app.FromEditField_2.Value;
        end

        % Callback function
        function ToEditField_2ValueChanged(app, event)
            app.ToPlot = app.ToEditField_2.Value;
        end

        % Button pushed function: plotallnodesButton
        function plotallnodesButtonPushed(app, event)
cla(app.UIAxes2)
sellected = app.NodeparameterListBox.Value;

for j = 1:app.CountN        
switch  sellected

             case 'Demand'
                    for i = 1:length(app.data{'time'})
                    x(i) = double(app.data{'time'}{i});
                    y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'demand'}{i});
                    end
                    plot(app.UIAxes2, x, y,'DisplayName', sprintf('demand fun (Node %d)', j)); 
            case 'Prorate'
                    for i = 1:length(app.data{'time'})
                    x(i) = double(app.data{'time'}{i});
                    y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'processing_rate'}{i});
                    end
                    plot(app.UIAxes2, x, y,"DisplayName",sprintf('processing rate (Node %d)', j));
            case 'Invin'
            
                    for i = 1:length(app.data{'time'})
                    x(i) = double(app.data{'time'}{i});
                    y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'invIn'}{i});
                    end
                plot(app.UIAxes2, x, y,"DisplayName",sprintf('invin (Node %d)', j));
                
            case 'Inout'
                    for i = 1:length(app.data{'time'})
                        x(i) = double(app.data{'time'}{i});
                        y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'invOut'}{i});
                    end
                    plot(app.UIAxes2, x, y,"DisplayName",sprintf('invout (Node %d)', j));
                    
            case 'cost'
                    for i = 1:length(app.data{'time'})
                    x(i) = double(app.data{'time'}{i});
                    y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'costs'}{i});
                    end
                    plot(app.UIAxes2, x, y,"DisplayName",sprintf('cost (Node %d)', j));
            case 'Rev'
                    for i = 1:length(app.data{'time'})
                    x(i) = double(app.data{'time'}{i});
                    y(i) = double(app.data{'nodes'}{app.NodeNameV{j}}{'revenue'}{i});
                    end
                    plot(app.UIAxes2, x, y,"DisplayName",sprintf('revenue (Node %d)', j));
end
        
       hold(app.UIAxes2, 'on');
       legend(app.UIAxes2, 'Location', 'best');
        
end
        end

        % Value changed function: LinknumberEditField
        function LinknumberEditFieldValueChanged2(app, event)
            app.LinkNumberPlot = app.LinknumberEditField.Value;
        end

        % Button pushed function: PlotalllinksButton
        function PlotalllinksButtonPushed(app, event)
cla(app.UIAxes3);
sellected = app.LinkparameterListBox.Value;

for j = 1:app.CountL
switch  sellected
            case 'UnitNow'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{j}{'unitsNow'}{i});
                end
                  plot(app.UIAxes3, x, y,"DisplayName",sprintf('unitsnow (Link %d)', j));
                  
            case 'UnitBack'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{j}{'unitsBack'}{i});
                end
                  plot(app.UIAxes3, x, y,"DisplayName",sprintf('unitsBack (Link %d)', j));
                  
            case 'Cost'
                for i = 1:length(app.data{'time'})
                x(i) = double(app.data{'time'}{i});
                y(i) = double(app.data{'links'}{j}{'cost'}{i});
                end
                  plot(app.UIAxes3, x, y,"DisplayName",sprintf('cost (Link %d)', j));
end
   
     legend(app.UIAxes3, 'Location', 'best');
    
end
        end

        % Value changed function: SupplyfunctionListBox
        function SupplyfunctionListBoxValueChanged(app, event)
            value = app.SupplyfunctionListBox.Value;
        end

        % Value changed function: DemandfunctionListBox
        function DemandfunctionListBoxValueChanged(app, event)
            value = app.DemandfunctionListBox.Value;
        end

        % Value changed function: DCoeffEditField
        function DCoeffEditFieldValueChanged(app, event)
            app.demandcoff = app.DCoeffEditField.Value;
        end

        % Value changed function: SCoeffEditField
        function SCoeffEditFieldValueChanged(app, event)
            app.supplycoff = app.SCoeffEditField.Value;
        end

        % Value changed function: TypeofnodeListBox
        function TypeofnodeListBoxValueChanged(app, event)
            value = app.TypeofnodeListBox.Value;
        end

        % Value changed function: FinancialofthesystemTextArea
        function FinancialofthesystemTextAreaValueChanged(app, event)
app.outputdis = app.FinancialofthesystemTextArea.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Supply Chain Network')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Node'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Link')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.AddnodeButton = uibutton(app.UIFigure, 'push');
            app.AddnodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddnodeButtonPushed, true);
            app.AddnodeButton.Text = 'Add node';
            app.NodeNameEditFieldLabel = uilabel(app.UIFigure);
            app.NodeNameEditFieldLabel.Text = 'NodeName';
            app.NodeNameEditField = uieditfield(app.UIFigure, 'text');
            app.NodeNameEditField.ValueChangedFcn = createCallbackFcn(app, @NodeNameEditFieldValueChanged, true);
            app.AddlinkButton = uibutton(app.UIFigure, 'push');
            app.AddlinkButton.ButtonPushedFcn = createCallbackFcn(app, @AddlinkButtonPushed, true);
            app.AddlinkButton.Text = 'Add link';
            app.CapOutEditFieldLabel = uilabel(app.UIFigure);
            app.CapOutEditFieldLabel.Text = 'CapOut';
            app.CapOutEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapOutEditField.ValueChangedFcn = createCallbackFcn(app, @CapOutEditFieldValueChanged, true);
            app.CapNodeEditFieldLabel = uilabel(app.UIFigure);
            app.CapNodeEditFieldLabel.Text = 'CapNode';
            app.CapNodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapNodeEditField.ValueChangedFcn = createCallbackFcn(app, @CapNodeEditFieldValueChanged, true);
            app.CapInEditFieldLabel = uilabel(app.UIFigure);
            app.CapInEditFieldLabel.Text = 'CapIn';
            app.CapInEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapInEditField.ValueChangedFcn = createCallbackFcn(app, @CapInEditFieldValueChanged, true);
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @FromEditFieldValueChanged, true);
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @ToEditFieldValueChanged, true);
            app.CapLinkEditFieldLabel = uilabel(app.UIFigure);
            app.CapLinkEditFieldLabel.Text = 'CapLink';
            app.CapLinkEditField = uieditfield(app.UIFigure, 'numeric');
            app.CapLinkEditField.ValueChangedFcn = createCallbackFcn(app, @CapLinkEditFieldValueChanged, true);
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.UnitStepLabel = uilabel(app.UIFigure);
            app.UnitStepLabel.Text = 'Unitsize';
            app.UnitsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitsizeEditField.ValueChangedFcn = createCallbackFcn(app, @UnitsizeEditFieldValueChanged, true);
            app.PlotthenetworkButton = uibutton(app.UIFigure, 'push');
            app.PlotthenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @PlotthenetworkButtonPushed, true);
            app.PlotthenetworkButton.Text = 'Plot the network';
            app.StartnewsystemButton = uibutton(app.UIFigure, 'push');
            app.StartnewsystemButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewsystemButtonPushed, true);
            app.StartnewsystemButton.Text = 'Start new system';
            app.PlotnodeButton = uibutton(app.UIFigure, 'push');
            app.PlotnodeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotnodeButtonPushed, true);
            app.PlotnodeButton.Text = 'Plot node';
            app.plotallnodesButton = uibutton(app.UIFigure, 'push');
            app.plotallnodesButton.ButtonPushedFcn = createCallbackFcn(app, @plotallnodesButtonPushed, true);
            app.plotallnodesButton.Text = {'plot all nodes'; ''};
            app.NodenumberEditFieldLabel = uilabel(app.UIFigure);
            app.NodenumberEditFieldLabel.Text = {'Node number'; ''};
            app.NodenumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NodenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NodenumberEditFieldValueChanged, true);
            app.HoldCheckBox = uicheckbox(app.UIFigure);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Text = 'Hold';
            app.PlotalllinksButton = uibutton(app.UIFigure, 'push');
            app.PlotalllinksButton.ButtonPushedFcn = createCallbackFcn(app, @PlotalllinksButtonPushed, true);
            app.PlotalllinksButton.Text = 'Plot all links';
            app.HoldCheckBox_2 = uicheckbox(app.UIFigure);
            app.HoldCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBox_2ValueChanged, true);
            app.HoldCheckBox_2.Text = 'Hold';
            app.PlotlinkButton = uibutton(app.UIFigure, 'push');
            app.PlotlinkButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlinkButtonPushed, true);
            app.PlotlinkButton.Text = 'Plot link';
            app.ClearButton_2 = uibutton(app.UIFigure, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.ClearButton_3 = uibutton(app.UIFigure, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.NodeparameterListBoxLabel = uilabel(app.UIFigure);
            app.NodeparameterListBoxLabel.Text = 'Node parameter';
            app.NodeparameterListBox = uilistbox(app.UIFigure);
            app.NodeparameterListBox.Items = {'Supply', 'Demand', 'Prorate', 'Invin', 'Inout', 'Cost', 'Rev'};
            app.NodeparameterListBox.ValueChangedFcn = createCallbackFcn(app, @NodeparameterListBoxValueChanged, true);
            app.NodeparameterListBox.Value = 'Demand';
            app.LinkparameterListBoxLabel = uilabel(app.UIFigure);
            app.LinkparameterListBoxLabel.Text = 'Link parameter';
            app.LinkparameterListBox = uilistbox(app.UIFigure);
            app.LinkparameterListBox.Items = {'UnitNow', 'UnitBack', 'Cost'};
            app.LinkparameterListBox.ValueChangedFcn = createCallbackFcn(app, @LinkparameterListBoxValueChanged, true);
            app.LinkparameterListBox.Value = 'UnitNow';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.DemandfunctionListBoxLabel = uilabel(app.UIFigure);
            app.DemandfunctionListBoxLabel.Text = 'Demand function';
            app.DemandfunctionListBox = uilistbox(app.UIFigure);
            app.DemandfunctionListBox.Items = {'triangle', 'unitstep', 'None'};
            app.DemandfunctionListBox.ValueChangedFcn = createCallbackFcn(app, @DemandfunctionListBoxValueChanged, true);
            app.DemandfunctionListBox.Value = 'triangle';
            app.SupplyfunctionListBoxLabel = uilabel(app.UIFigure);
            app.SupplyfunctionListBoxLabel.Text = 'Supply function';
            app.SupplyfunctionListBox = uilistbox(app.UIFigure);
            app.SupplyfunctionListBox.Items = {'triangle', 'unitstep', 'None'};
            app.SupplyfunctionListBox.ValueChangedFcn = createCallbackFcn(app, @SupplyfunctionListBoxValueChanged, true);
            app.SupplyfunctionListBox.Value = 'triangle';
            app.TypeofnodeListBoxLabel = uilabel(app.UIFigure);
            app.TypeofnodeListBoxLabel.Text = 'Type of node';
            app.TypeofnodeListBox = uilistbox(app.UIFigure);
            app.TypeofnodeListBox.Items = {'Source', 'Drain', 'Both', 'Intermadiate'};
            app.TypeofnodeListBox.ValueChangedFcn = createCallbackFcn(app, @TypeofnodeListBoxValueChanged, true);
            app.TypeofnodeListBox.Value = 'Source';
            app.InInvEditFieldLabel = uilabel(app.UIFigure);
            app.InInvEditFieldLabel.Text = 'InInv';
            app.InInvEditField = uieditfield(app.UIFigure, 'numeric');
            app.InInvEditField.ValueChangedFcn = createCallbackFcn(app, @InInvEditFieldValueChanged, true);
            app.FinancialofthesystemTextAreaLabel = uilabel(app.UIFigure);
            app.FinancialofthesystemTextAreaLabel.Text = 'Financial of the system';
            app.FinancialofthesystemTextArea = uitextarea(app.UIFigure);
            app.FinancialofthesystemTextArea.ValueChangedFcn = createCallbackFcn(app, @FinancialofthesystemTextAreaValueChanged, true);
            app.VarcostEditFieldLabel = uilabel(app.UIFigure);
            app.VarcostEditFieldLabel.Text = 'Var cost';
            app.VarcostEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarcostEditField.ValueChangedFcn = createCallbackFcn(app, @VarcostEditFieldValueChanged, true);
            app.FixedcostEditField_2Label = uilabel(app.UIFigure);
            app.FixedcostEditField_2Label.Text = 'Fixed cost';
            app.FixedcostEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FixedcostEditField_2.ValueChangedFcn = createCallbackFcn(app, @FixedcostEditField_2ValueChanged, true);
            app.ProcostEditFieldLabel = uilabel(app.UIFigure);
            app.ProcostEditFieldLabel.Text = 'Pro cost';
            app.ProcostEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcostEditField.ValueChangedFcn = createCallbackFcn(app, @ProcostEditFieldValueChanged, true);
            app.RevEditFieldLabel = uilabel(app.UIFigure);
            app.RevEditFieldLabel.Text = 'Rev';
            app.RevEditField = uieditfield(app.UIFigure, 'numeric');
            app.RevEditField.ValueChangedFcn = createCallbackFcn(app, @RevEditFieldValueChanged, true);
            app.VeractionEditFieldLabel = uilabel(app.UIFigure);
            app.VeractionEditFieldLabel.Text = '-Ve raction';
            app.VeractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.VeractionEditField.ValueChangedFcn = createCallbackFcn(app, @VeractionEditFieldValueChanged, true);
            app.TimebackEditFieldLabel = uilabel(app.UIFigure);
            app.TimebackEditFieldLabel.Text = 'Time back';
            app.TimebackEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimebackEditField.ValueChangedFcn = createCallbackFcn(app, @TimebackEditFieldValueChanged, true);
            app.TimedivEditFieldLabel = uilabel(app.UIFigure);
            app.TimedivEditFieldLabel.Text = 'Time div';
            app.TimedivEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimedivEditField.ValueChangedFcn = createCallbackFcn(app, @TimedivEditFieldValueChanged, true);
            app.UnitcostEditFieldLabel = uilabel(app.UIFigure);
            app.UnitcostEditFieldLabel.Text = 'Unitcost';
            app.UnitcostEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnitcostEditField.ValueChangedFcn = createCallbackFcn(app, @UnitcostEditFieldValueChanged, true);
            app.VereactionfixedEditFieldLabel = uilabel(app.UIFigure);
            app.VereactionfixedEditFieldLabel.Text = '-Ve reaction fixed';
            app.VereactionfixedEditField = uieditfield(app.UIFigure, 'numeric');
            app.VereactionfixedEditField.ValueChangedFcn = createCallbackFcn(app, @VereactionfixedEditFieldValueChanged, true);
            app.VereactionvarEditFieldLabel = uilabel(app.UIFigure);
            app.VereactionvarEditFieldLabel.Text = '-Ve reaction var';
            app.VereactionvarEditField = uieditfield(app.UIFigure, 'numeric');
            app.VereactionvarEditField.ValueChangedFcn = createCallbackFcn(app, @VereactionvarEditFieldValueChanged, true);
            app.errorEditFieldLabel = uilabel(app.UIFigure);
            app.errorEditFieldLabel.Text = 'error';
            app.errorEditField = uieditfield(app.UIFigure, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @errorEditFieldValueChanged, true);
            app.TimeperiodtosimulateEditFieldLabel = uilabel(app.UIFigure);
            app.TimeperiodtosimulateEditFieldLabel.Text = 'Time period to simulate';
            app.TimeperiodtosimulateEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeperiodtosimulateEditField.ValueChangedFcn = createCallbackFcn(app, @TimeperiodtosimulateEditFieldValueChanged, true);
            app.DCoeffEditFieldLabel = uilabel(app.UIFigure);
            app.DCoeffEditFieldLabel.Text = 'D Coeff';
            app.DCoeffEditField = uieditfield(app.UIFigure, 'numeric');
            app.DCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @DCoeffEditFieldValueChanged, true);
            app.LinknumberEditFieldLabel = uilabel(app.UIFigure);
            app.LinknumberEditFieldLabel.Text = {'Link number'; ''};
            app.LinknumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinknumberEditField.ValueChangedFcn = createCallbackFcn(app, @LinknumberEditFieldValueChanged2, true);
            app.SCoeffEditFieldLabel = uilabel(app.UIFigure);
            app.SCoeffEditFieldLabel.Text = 'S Coeff';
            app.SCoeffEditField = uieditfield(app.UIFigure, 'numeric');
            app.SCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @SCoeffEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Miadas_touch_FV2_front

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end